classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TumorExtraction     matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        InterfaceTab        matlab.ui.container.Tab
        BrowseButton        matlab.ui.control.Button
        MRImage             matlab.ui.control.UIAxes
        Threshold           matlab.ui.control.UIAxes
        Watershed           matlab.ui.control.UIAxes
        ProcessButton       matlab.ui.control.Button
        Label               matlab.ui.control.Label
        GenerateButton      matlab.ui.control.Button
        GenerateButton_2    matlab.ui.control.Button
        Directory           matlab.ui.control.EditField
        Tumor               matlab.ui.control.UIAxes
        CodeTab             matlab.ui.container.Tab
        TextArea            matlab.ui.control.TextArea
        TextArea_2          matlab.ui.control.TextArea
        FunctionDetailsTab  matlab.ui.container.Tab
        TextArea_3          matlab.ui.control.TextArea
        TextArea_4          matlab.ui.control.TextArea
        EditField           matlab.ui.control.EditField
    end


    properties (Access = private)
        Image % Description
        Lrgb
        Thres % Description
        count
    end


    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            if isequal(file,0)
                app.Directory.Value = 'Status : Process Terminated by User';
                pause(1);
                app.Directory.Value = 'Click Browse to select MRI images...';
            else
                app.Image= imread(file);
                app.Image= imresize(app.Image, [224, 224]);
                imshow(app.Image, 'parent', app.MRImage);
                app.Directory.Value = path;
            end
            pause(1);
            app.Directory.Value = 'Click on Process to generate "Expected Tumor" region...';
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.count = 1;
            
            if (app.count == 1)
                app.Directory.Value = 'Click on Generate to see step-by-step processed images...';
            end
            
            I= app.Image;
            I= rgb2gray(I);
            I = medfilt2(I);
            I= imbinarize(I,.6);%binarising with thresold .6
            app.Thres = I;
            
            hy = fspecial('sobel');
            hx = hy';
            Iy = imfilter(double(I), hy, 'replicate');
            Ix = imfilter(double(I), hx, 'replicate');
            gradmag = sqrt(Ix.^2 + Iy.^2);
            L = watershed(gradmag);
            app.Lrgb = label2rgb(L);
            
            
            se = strel('disk', 20);
            Io = imopen(I, se);
            Ie = imerode(I, se);
            Iobr = imreconstruct(Ie, I);
            Iobrd = imdilate(Iobr, se);
            Iobrcbr = imreconstruct(imcomplement(Iobrd), imcomplement(Iobr));
            Iobrcbr = imcomplement(Iobrcbr);
            
            I2 = I;
            fgm = imregionalmax(Iobrcbr);
            I2(fgm) = 255;
            se2 = strel(ones(5,5));
            fgm2 = imclose(fgm, se2);
            fgm3 = imerode(fgm2, se2);
            fgm4 = bwareaopen(fgm3, 20);
            
            I3 = I;
            bw = im2bw(Iobrcbr);
            imshow(bw, 'parent', app.Tumor);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            imshow(app.Thres, 'parent', app.Threshold);
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            imshow(app.Lrgb, 'parent', app.Watershed);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TumorExtraction = uifigure;
            app.TumorExtraction.Name = 'Tumor Extraction';
            app.TabGroup = uitabgroup(app.TumorExtraction);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.BrowseButton = uibutton(app.InterfaceTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MRImage = uiaxes(app.InterfaceTab);
            title(app.MRImage, 'MRI Image')
            app.Threshold = uiaxes(app.InterfaceTab);
            title(app.Threshold, 'Thresholded Image')
            app.Watershed = uiaxes(app.InterfaceTab);
            title(app.Watershed, 'Watershed segmented image')
            app.ProcessButton = uibutton(app.InterfaceTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Label = uilabel(app.InterfaceTab);
            app.Label.Text = 'By: Raj Kishore Patra (B217030)';
            app.GenerateButton = uibutton(app.InterfaceTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.GenerateButton_2 = uibutton(app.InterfaceTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.Directory = uieditfield(app.InterfaceTab, 'text');
            app.Directory.Editable = 'off';
            app.Directory.Value = 'Welcome! Click Browse to select MRI images.';
            app.Tumor = uiaxes(app.InterfaceTab);
            title(app.Tumor, 'Expected Tumor')
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.TextArea = uitextarea(app.CodeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'% --------- MATlab Code for Tumor extraction -----------'; '%% -----------------------------------------------------------------'; ''; 'image_data=imread(''Enter File Path Here'');'; 'figure,'; '    imshow(image_data); '; '    title(''Brain MRI Image'');'; '    '; '%% -----------------------------------------------------------------'; '% Generation of Threshold Image'; ''; 'image_data = imresize(image_data,[224,224]);'; 'image_data= rgb2gray(image_data);'; 'image_data = medfilt2(image_data);'; 'image_data= im2bw(image_data,.6);'; ''; 'figure, '; '    imshow(image_data);'; '    title(''Thresholded Image'');'; '%% -----------------------------------------------------------------'; '% Generation of Segmented Image'; ''; 'hy = fspecial(''sobel'');'; 'hx = hy'';'; 'Iy = imfilter(double(image_data), hy, ''replicate'');'; 'Ix = imfilter(double(image_data), hx, ''replicate'');'; 'gradmag = sqrt(Ix.^2 + Iy.^2);'; 'L = watershed(gradmag);'; 'Lrgb = label2rgb(L);'; ''; 'figure,'; '    imshow(Lrgb); '; '    title(''Watershed segmented image'');'; '%% -----------------------------------------------------------------'};
            app.TextArea_2 = uitextarea(app.CodeTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {''; '%% -----------------------------------------------------------------'; '% Extracting Tumor object from the Image Data'; ''; 'se = strel(''disk'', 20);'; ''; '% where ''se'' is called the structuring element.'; ''; 'Io = imopen(image_data, se);'; 'Ie = imerode(image_data, se);'; ''; 'Iobr = imreconstruct(Ie, image_data);'; 'Iobrd = imdilate(Iobr, se);'; 'Iobrcbr = imreconstruct(imcomplement(Iobrd), imcomplement(Iobr));'; 'Iobrcbr = imcomplement(Iobrcbr);'; ''; 'I2 = image_data;'; 'fgm = imregionalmax(Iobrcbr);'; 'I2(fgm) = 255;'; ''; 'se2 = strel(ones(5,5));'; 'fgm2 = imclose(fgm, se2);'; 'fgm3 = imerode(fgm2, se2);'; 'fgm4 = bwareaopen(fgm3, 20);'; ''; 'I3 = imerode(image_data, se);'; 'bw = im2bw(Iobrcbr);'; 'figure'; '    imshow(bw);'; '    title(''only tumor'');'; ''; '%% -----------------------------------------------------------------'};
            app.FunctionDetailsTab = uitab(app.TabGroup);
            app.FunctionDetailsTab.Title = 'Function Details';
            app.TextArea_3 = uitextarea(app.FunctionDetailsTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'>>> imread() - Read image from graphics file.'; '	- Takes file name, file format or part of the image as parameters to read from.'; ''; '>>> imshow() - Display image from an image file.'; '	- Takes either a file name or image data with or without color map as parameters.'; ''; '>>> imresize() - Resizes the image passes based on input parameter.'; '	- Takes image data and dimensions to resize as parameters.'; ''; '>>> imbinarize() - Binarize 2-D grayscale image or 3-D volume by thresholding.'; '	- Takes image data and a thresholding method as parameters.'; ''; '>>> imfilter() - N-D filtering of multidimensional images based on boundary options.'};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
            app.TextArea_4 = uitextarea(app.FunctionDetailsTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Methods used from the "Image Processing Toolkit"'};
            app.EditField = uieditfield(app.TumorExtraction, 'text');
            app.EditField.Value = 'Brain Tumor Extraction using Image Processing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TumorExtraction)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TumorExtraction)
        end
    end
end